classdef AutoCurve < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        CancelButton           matlab.ui.control.StateButton
        TextArea               matlab.ui.control.TextArea
        LensTypeDropDown       matlab.ui.control.DropDown
        LensTypeDropDownLabel  matlab.ui.control.Label
        ResolutionDropDown     matlab.ui.control.DropDown
        Label                  matlab.ui.control.Label
        PolyButton             matlab.ui.control.Button
        RightPanel             matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        imageHeight;
        imageWidth;
        lensType;
        lensMark;
        STOP = 0; % Description
    end
    
    methods (Access = private)
        
        function CurveData = CurveMade_NoTec(~,tempRange,options)
            
            R = options(1);
            B = options(2);
            F = options(3);
            O = options(4);
            Y16 = @(x)R./(exp(B./(x+273))-F)+O;


            CurveData = Y16(tempRange / 10);
%             CurveData = double(CurveData);
            CurveData = round(CurveData);
            for i = 1:size(CurveData)
                if (CurveData(i) <= 0)
                    CurveData(1:i) = 0;

                else
                    continue
                end
            end
            for i = size(CurveData) : 1
                if (CurveData(i) >= 16383)
                    CurveData(i:size(CurveData)) = 16383;
                else
                    continue
                end
            end
        end
       
        function [testT,rmse] = Tem2CurveY16(~,y16,t,options)
            
            R = options(1);
            B = options(2);
            F = options(3);
            O = options(4);
            T = @(y)B./log(F - R./(O - y)) - 273;
            testT = T(y16);
%             testT = double(testT);
            dk = (abs(testT -t)).^2;
            sse = 0;
            for i = 1:length(t)
                if t(i) <= 100
                    sse = sse + dk(i);
                else
                    sse = sse + dk(i)*100/t(i);
                end
            end
            rmse = sqrt( sse/length(dk));
        end
        
        function testY16 = GetCurveY16(~,t,options)
            
            R = options(1);
            B = options(2);
            F = options(3);
            O = options(4);

            Y16 = @(x)R./(exp(B./(x+273))-F)+O;
            testY16 = Y16(t);
%             testY16 = double(testY16);
        end
        
        function [fitResult,gof] = createFit(~,T,Y16)
            [xData, yData] = prepareCurveData( T, Y16 );
            ft = fittype( 'R/(exp(B/(x+273))-F)+O', 'independent', 'x', 'dependent', 'y' );
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' ,'Algorithm', 'Levenberg-Marquardt');%Levenberg-Marquardt
            opts.Display = 'off';
            opts.TolFun = 1e-30;
            opts.TolX = 1e-30;
            opts.MaxIter = 50000;
            opts.MaxFunEvals = 50000;
            opts.StartPoint = rand(1,4);
            [fitResult, gof] = fit( xData, yData, ft, opts );
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PolyButton
        function PolyButtonPushed(app, event)
            value = app.LensTypeDropDown.Value;
            switch value
                case '标准镜头'
                    app.lensType = 1;
                    app.lensMark = 'ita_standard';
                case '微距镜头'
                    app.lensType = 10;
                    app.lensMark = 'ita_micro';
                case '广角镜头'
                    app.lensType = 7;
                    app.lensMark = 'ita_wideangle';
                case '中长焦镜头'
                    app.lensType = 8;
                    app.lensMark = 'ita_tele';
                case '长焦镜头'
                    app.lensType = 9;
                    app.lensMark = 'ita_85_tele';
                case '高温镜头'
                    app.lensType = 11;
                    app.lensMark = 'ita_high_temp_high';
                otherwise
                    error('错误输入镜头类型');

            end

            resulation = app.ResolutionDropDown.Value;
            app.imageWidth = str2double(resulation(1:3));
            app.imageHeight = str2double(resulation(5:7));
            
            
            app.ResolutionDropDown.Enable = "off";
            app.LensTypeDropDown.Enable = "off";
            app.PolyButton.Enable = "off";
            mess = '';
            start_path = '.';
            PATH = uigetdir(start_path, 'select a folder:');   % 通过UI界面选择想要读取的文件夹
            if(PATH == 0)
            error('选择文件夹错误');
            end
            file = dir(fullfile(PATH,'*.xls'));                                     % 获取文件夹下的所有文件名称
            fileName = {file.name}';            % 提取符合后缀名为.txt的所有文件的文件名，转换为n行1列
            Number_Files = size(fileName,1);
            for k = 1 : Number_Files
                %创建存放对应编号曲线的文件夹
                fileName_char = fileName{k};
                Num = fileName_char(1:3);
                mkdir(PATH,Num);
                %读取对应采集的原始曲线
                file_Trace = [PATH, '\',fileName{k}];
                original_data = readcell(file_Trace);
                mark = fileName_char(5:8);
                path = [PATH,'\',Num,'\'];
                
                if(strcmp(mark,'low.'))
                    
                    fid = fopen([path app.lensMark '_low.raw'],'w');
                    fid1 = fopen([path app.lensMark '_low.txt'],'w');
                    fid2 = fopen([path app.lensMark '_ITA3.0_low.raw'],'w');
                    temp = [-9.2 33.7 96.4 144.9;
                            -8.6 34.1 96.7 145.1;
                            -8.0 34.4 96.9 145.3;
                            2.1 35 97.3 145.6];

                    temp = temp + 50; %低测温范围
                elseif(strcmp(mark,'high'))
                    fid2 = fopen([path app.lensMark '_ITA3.0_high.raw'],'w');
                    fid = fopen([path app.lensMark '_high.raw'],'w');
                    fid1 = fopen([path app.lensMark '_high.txt'],'w');
                    
                    temp = [33.7 96.4 290.1 627.2;
                            34.1 96.7 290.3 627.3;
                            34.4 96.9 290.4 627.4;
                            35.0 97.3 290.6 627.5];
                  
                    temp = temp + 20;%ZC16A高测温范围
                else
                    fid = fopen([path app.lensMark '_ultra.raw'],'w');
                    fid1 = fopen([path app.lensMark '_ultra.txt'],'w');
                    fid2 = fopen([path app.lensMark '_ITA3.0_ultra.raw'],'w');
                    temp = [96.4 290.11 627.2 962.5 1440.1;
                            96.7 290.25 627.3 962.6 1440.2;
                            96.9 290.40 627.4 962.7 1440.2;
                            97.3 290.57 627.5 962.8 1440.4;];

                    temp = temp + 0;
                end

                y16 = zeros(6,size(temp,2));
                focus_temp = [1500,zeros(1,4),0];

                for i =1 : 4
                    focus_temp(i + 1) = int16(100 * str2double(original_data{i + 1,2}));
                end
               %更新延申曲线的焦温 有些可能35采集时焦温已经超过70℃
                focus_temp(6) = max(focus_temp) + 800;

%                 if(size(original_data,2) - 2 ~= size(temp,2))
%                     error('曲线采集表格错误');
%                 end
                for i = 1:4
                    for j = 1 : size(temp,2)
                        
                        if(strcmp(mark,'low.'))
                        %从数据中选取拟合点
                        index = [1,4,6,7];
                        ji = index(j);
                        y16(i + 1,j) = int16(str2double(original_data{i + 1,ji + 2}));
                        
                        elseif(strcmp(mark,'high')) %高测温范围
                        index = [3,5,8,11];
                        ji = index(j);
                        y16(i + 1,j) = int16(str2double(original_data{i + 1,ji + 2})); %- int16(str2double(original_data{i + 1,3})) + 3000;
                        else %超高测温范围
                        index = [2,4,6,8,9];
                        ji = index(j);
                        y16(i + 1,j) = int16(str2double(original_data{i + 1,ji + 2})); %- int16(str2double(original_data{i + 1,4})) + 3000;
                        end
                    end
                end
                
                %按焦温递增排序
                for m = 1:6
                    for n = m + 1:6
                        if(focus_temp(n) < focus_temp(m))
                            a = focus_temp(m);
                            ay16 = y16(m,:);
                            focus_temp(m) = focus_temp(n);
                            y16(m,:) = y16(n,:);
                            focus_temp(n) = a;
                            y16(n,:) = ay16;
                        end
                    end
                end
                
                %高、低焦温曲线外延
%                 y16(1,:) = int16(y16(2,:) + (focus_temp(1) - focus_temp(2)) * (y16(2,:) - y16(3,:)) / (focus_temp(2) - focus_temp(3)));
% 
%                 %高测温范围正常高外延
%                 if(~strcmp(mark,'low.'))
%                 y16(6,:) = int16(y16(5,:) + (focus_temp(6) - focus_temp(5)) * (y16(5,:) - y16(4,:)) / (focus_temp(5) - focus_temp(4)));
%                 end
                %开始写曲线包
                
                headLength = 216;
                fwrite(fid,headLength,'int32');
                moduleMtMark = [84 73 95 67	65	76	95	77	69	84	72	79	68	95	82	69	88	95	68	73	83	84	65	78	67	69	95	69	77	73	83	83	95	66	71	95	84	82	65	78	83	77	73	83	83	73	86	73	84	89	95	50	0	0	0	0	0	0	0	0];
                fwrite(fid,moduleMtMark,'char');
                gearMark = 0;
                fwrite(fid,gearMark,'uint8');
                fcousNumber = 6;
               
                fwrite(fid,fcousNumber,'uint8');
                if(strcmp(mark,'low.'))
                tempMin = -50;
                tempMax = 180;
                mtType = 1;
                elseif(strcmp(mark,'high'))
                    tempMin = -20;
                    tempMax = 680;
                    mtType = 2;
                else
                    tempMin = 0;
                    tempMax = 1500;
                    mtType = 3;
                
                end
                fwrite(fid,tempMin,'int16');
                fwrite(fid,tempMax,'int16');
                gain = 39;
                fwrite(fid,gain,'uint8');
                int = 7;
                fwrite(fid,int,'uint8');
                res = 32;
                fwrite(fid,res,'uint8');
                curveNumber = 10;
                fwrite(fid,curveNumber,'uint8');
                distCompensateMode = 2;
                fwrite(fid,distCompensateMode,'uint8');
                distNumber = 2;
                fwrite(fid,distNumber,'uint8');
                %图像分辨率重要
                
                fwrite(fid,app.imageWidth,'uint16');
                
                fwrite(fid,app.imageHeight,'uint16');

                
                curveTempNum = (tempMax - tempMin) * 10;
                fwrite(fid,curveTempNum,'uint16');
                focusArrayLength = 12;
                fwrite(fid,focusArrayLength,'uint16');
                curveDataLength = 124000;
                fwrite(fid,curveDataLength,'uint32');
                kMatLength = 491520;
                fwrite(fid,kMatLength,'uint32');
                distCompensateCoef = zeros(1,6);
                fwrite(fid,distCompensateCoef,'float');
                distArray = [5,15,0,0,0];
                fwrite(fid,distArray,'uint16');
                date = zeros(1,10);
                fwrite(fid,date,'char');
                time = zeros(1,10);
                fwrite(fid,time,'char');
                moduleCode = zeros(1,24);
                fwrite(fid,moduleCode,'uint8');
                LiaoHao = zeros(1,12);
                fwrite(fid,LiaoHao,'uint8');
                %换成SN
                SheBeiBianHao = 0;
                fwrite(fid,SheBeiBianHao,'uint8');
                ZhiJuHao = 0;
                fwrite(fid,ZhiJuHao,'uint8');
                KaChaoHao = 0;
                fwrite(fid,KaChaoHao,'uint8');
                DingBiaoHeiTiNum = 4;
                fwrite(fid,DingBiaoHeiTiNum,'uint8');
                focustype = 1;
                fwrite(fid,focustype,'uint8');
                %镜头类型 重要 回调输入
          
                fwrite(fid,app.lensType,'uint8');
                %根据测温范围输入

                fwrite(fid,mtType,'uint8');
                correctDist = 5;
                fwrite(fid,correctDist,'float');
                correctError = zeros(1,19);
                fwrite(fid,correctError,'uint8');
                mtPointX = 0;
                fwrite(fid,mtPointX,'uint16');
                mtPointY = 0;
                fwrite(fid,mtPointY,'uint16');
                sensorParam = zeros(70,10);
                fwrite(fid,sensorParam,'uint16');

                %写入焦温数据
                fwrite(fid,focus_temp,'uint16');
                
                %增加曲线参数 20241108
                Curve_param = ones(6,4);
                Tmin = 0;
                Tmax = tempMax - tempMin;
                Temp_Range = linspace(Tmin * 10,Tmax * 10,(Tmax - Tmin) * 10);
                CurveData = ones(size(focus_temp,2),size(Temp_Range,2));
                nc = 0 ;
                mess1 = '成功';
                for i = 2:size(focus_temp,2) - 1
%                     if(strcmp(mark,'low.'))
                    T = temp(i - 1,:);
%                     if(i == 5||i == 6)
%                         T = [0 35 100 150] + 50;
% 
%                     end

                    Y16 = y16(i,:);
%                     if(i == 6)
%                        delta = (focus_temp(6) - focus_temp(5)) / (focus_temp(5) - focus_temp(4));
%                         Y16(:) = CurveData(5,T(:) * 10) + (CurveData(5,T(:) * 10) - CurveData(4,T(:) * 10)) * delta;
%                         
% %                        Y16(1) = 3000; % int16(y16(6,1) + (focus_temp(7) - focus_temp(6)) * (y16(6,1) - CurveData(5,0 - tempMin * 10)) / (focus_temp(6) - focus_temp(5)));
% %                        delta = (focus_temp(7) - focus_temp(6)) * (CurveData(5,0 - tempMin* 10) - CurveData(5,(-10 - tempMin) * 10)) / (focus_temp(6) - focus_temp(5));
% %                        Y16(2:end) = int16(y16(7,2:end) + delta);
%                     end
                    
%                     else
%                     T= temp;
%                     Y16 = y16(i,:);
%                     end
                    
                    t = T;
                    yi = Y16;

                    n = 0;
                    rmse_min = 10;
%                     testT_min = ones(1,4);
                    options_min = ones(1,4);
                    mess_mark = '';
                    time = 0;
                    tic;
                    while ((n < 20)&&(rmse_min > 1))%||((options_min(2) / options_min(3)) < 0)
                        pause(0.001);
                        if (app.STOP == 0)

                            [fitresult,~] = app.createFit(T,Y16);
                            time = time + toc;
                            options  = [fitresult.R,fitresult.B,fitresult.F,fitresult.O];
                            format long g;
                            [~,rmse] = app.Tem2CurveY16(yi,t,options);
                        if rmse< rmse_min
                            rmse_min = rmse;
                            options_min = options;
                            
                        end
                        if (time > 120&&rmse_min > 1.5)
                            mess1 = '失败';
                            break;
                            
                        end
                        n = n+1;
                        else
                            mess_mark = '取消';
                            app.STOP = 0;
                            break;
                        end
                    end
%                     disp(testT_min);
                    if (n == 20&&rmse_min > 1.5)
                        mess1 = '失败';
                    end

                    if (strcmp(mess_mark,"取消"))
                        break;
                    else
                        nc = nc + 1;


                        %35℃环温曲线特殊处理
                        CurveData(i,:) = app.CurveMade_NoTec(Temp_Range,options_min);
                        %全部曲线以0℃为基础点
                        %20241214修改：超高测温范围以100℃为基础点;定义基础点
                        basePoint = 0;%低温和常温高温档使用0作为基础点；
                      
                        if(strcmp(mark,'utra'))
                        basePoint = 100;
                        end
                        CurveData(i,:) = CurveData(i,:) - CurveData(i,(basePoint - tempMin) * 10 + 1) + 3000;

                         %增加存储曲线参数 20241108
                        Curve_param(i,:) = options_min;
                         %修改曲线端点一致化错误
%                          syms a b c d x;
%                          y_ = a./(exp(b./(x + 273)) - c) + d;
%                          diff = double(subs(y_,[a,b,c,d,x],[Curve_param(i,:),0 - tempMin]));
                        y_ = @(a,b,c,d,x)a./(exp(b./(x + 273)) - c) + d;
                        diff = y_(Curve_param(i,1),Curve_param(i,2),Curve_param(i,3),Curve_param(i,4),basePoint - tempMin);
%                          
                        Curve_param(i,4) = Curve_param(i,4) - diff + 3000;


%                         if( (i < 6)&&strcmp(mark,'low.'))
%                         CurveData(i,:) = CurveData(i,:) - CurveData(i,0 - tempMin * 10) + 3000;
%                         end
%                         if( (i ==6||i== 7)&&strcmp(mark,'low.'))
%                             %                             options_min(3) = options_min(3) + 10;
%                             CurveData(i,:) = CurveData(i,:) + (CurveData(i,0 - tempMin * 10) - CurveData(i,(-10 - tempMin)* 10));
%                         end
%                         fseek(fid,1630 + (i - 1) * curveTempNum * 2 * 2,"bof");
%                         fwrite(fid,CurveData(i,:),"uint16");
%                         fwrite(fid,CurveData(i,:),"uint16");
                        
%                         plot(app.UIAxes,Temp_Range,CurveData(2:i,:),'LineWidth',2);
%                         pause(0.5);
                    end
                end
                CurveData(1,:) = CurveData(2,:) + (focus_temp(1) - focus_temp(2)) * (CurveData(2,:) - CurveData(3,:)) ./ (focus_temp(2) - focus_temp(3));
                CurveData(6,:) = CurveData(5,:) + (focus_temp(6) - focus_temp(5)) * (CurveData(5,:) - CurveData(4,:)) ./ (focus_temp(5) - focus_temp(4));
                Y16_ = ones(2,4);
                if(strcmp(mark,'low.'))
                    T_ = [0 35 100 150] + 50;
                   
                    Y16_ = Y16_(:,1:4);
                elseif(strcmp(mark,'high'))
                    T_ = [35 100 300 650] + 20;
                    
                    Y16_ = Y16_(:,1:4);
                else
                    T_ = [100 300 650 1000 1500];
                    Y16_ = [Y16_(:,1:4),ones(2,1)];
                end
                Y16_(1,:) = CurveData(1,(T_) * 10);
                Y16_(2,:) = CurveData(6,(T_) * 10);

                rmse_min = 10;
                options_min = ones(1,4);
                n = 0;
                while ((n < 20)&&(rmse_min > 1))


                        [fitresult,~] = app.createFit(T_,Y16_(1,:));
                        
                        options  = [fitresult.R,fitresult.B,fitresult.F,fitresult.O];
                        format long g;
                        [~,rmse] = app.Tem2CurveY16(Y16_(1,:),T_,options);
                        if rmse< rmse_min
                            rmse_min = rmse;
                            options_min = options;

                        end
                        n = n + 1;
                end
                Curve_param(1,:) = options_min;

                rmse_min = 10;
                options_min = ones(1,4);
                n = 0;
                while ((n < 20)&&(rmse_min > 1))


                        [fitresult,~] = app.createFit(T_,Y16_(2,:));
                        
                        options  = [fitresult.R,fitresult.B,fitresult.F,fitresult.O];
                        format long g;
                        [~,rmse] = app.Tem2CurveY16(Y16_(2,:),T_,options);
                        if rmse< rmse_min
                            rmse_min = rmse;
                            options_min = options;

                        end
                        n = n + 1;
                end
                Curve_param(6,:) = options_min;


                        %                 Curve_param(1,[1,4]) = Curve_param(2,[1,4]) + (focus_temp(1) - focus_temp(2)) * (Curve_param(2,[1,4]) - Curve_param(3,[1,4])) ./ (focus_temp(2) -focus_temp(3));
                        %
                        %                 Curve_param(6,[1,4]) = Curve_param(5,[1,4]) + (focus_temp(6) - focus_temp(5)) * (Curve_param(5,[1,4]) - Curve_param(4,[1,4])) ./ (focus_temp(5) -focus_temp(2));

                       
                        for i = 1:size(focus_temp,2)
                            fwrite(fid,CurveData(i,:),"uint16");
                            fwrite(fid,CurveData(i,:),"uint16");
                        end
                        fclose(fid);
                        bytenum = 114;
                        fwrite(fid2,bytenum,'int32');
                        fwrite(fid2,size(focus_temp,2),"int16");
                        for l = 1:6
                            fprintf(fid1,' %d %f %f %f %f \n',focus_temp(l),Curve_param(l,:));
                            fwrite(fid2,focus_temp(l),"int16");
                            fwrite(fid2,Curve_param(l,:),"single");
                        end
                        fclose(fid1);
                        fclose(fid2);
                        h = plot(app.UIAxes,Temp_Range,CurveData(1:6,:),'LineWidth',2);
                        legend(h,{'低温外延','5℃','15℃','25℃','35℃','高温外延'},'Location','northwest');
                        mess = char(mess,[Num  mark  mess1]);
                        app.TextArea.Value = string(mess);
                        if (strcmp(mess_mark,"取消"))
                            mess = char(mess,mess_mark);
                            app.TextArea.Value = string(mess);
                            break;
                    end
            end

            app.ResolutionDropDown.Enable = "on";
            app.LensTypeDropDown.Enable = "on";
            app.PolyButton.Enable = "on";
            
           
        end

        % Value changed function: ResolutionDropDown
        function ResolutionDropDownValueChanged(app, event)

        end

        % Value changed function: LensTypeDropDown
        function LensTypeDropDownValueChanged(app, event)

        end

        % Value changed function: CancelButton
        function CancelButtonValueChanged(app, event)
            app.STOP = 1;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {458, 458};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PolyButton = uibutton(app.LeftPanel, 'push');
            app.PolyButton.ButtonPushedFcn = createCallbackFcn(app, @PolyButtonPushed, true);
            app.PolyButton.Text = 'Poly';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.LeftPanel);
            app.ResolutionDropDown.Items = {'640*480', '480*360', '384*288', '320*240', '256*192'};
            app.ResolutionDropDown.ValueChangedFcn = createCallbackFcn(app, @ResolutionDropDownValueChanged, true);
            app.ResolutionDropDown.Value = '640*480';
            app.LensTypeDropDownLabel = uilabel(app.LeftPanel);
            app.LensTypeDropDownLabel.Text = 'Lens Type';
            app.LensTypeDropDown = uidropdown(app.LeftPanel);
            app.LensTypeDropDown.Items = {'标准镜头', '微距镜头', '广角镜头', '中长焦镜头', '长焦镜头', '高温镜头'};
            app.LensTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @LensTypeDropDownValueChanged, true);
            app.LensTypeDropDown.Value = '标准镜头';
            app.TextArea = uitextarea(app.LeftPanel);
            app.CancelButton = uibutton(app.LeftPanel, 'state');
            app.CancelButton.ValueChangedFcn = createCallbackFcn(app, @CancelButtonValueChanged, true);
            app.CancelButton.Text = 'Cancel';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'curve')
            xlabel(app.UIAxes, 'Temp')
            ylabel(app.UIAxes, 'Y16')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AutoCurve

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end