classdef CurveAdjust < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Label_2                 matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        UITable2                matlab.ui.control.Table
        OLabel                  matlab.ui.control.Label
        FLabel                  matlab.ui.control.Label
        BLabel                  matlab.ui.control.Label
        RLabel                  matlab.ui.control.Label
        DEditField              matlab.ui.control.NumericEditField
        CEditField              matlab.ui.control.NumericEditField
        BEditField              matlab.ui.control.NumericEditField
        AEditField              matlab.ui.control.NumericEditField
        UITable                 matlab.ui.control.Table
        FoucsGearDropDown       matlab.ui.control.DropDown
        FoucsGearDropDownLabel  matlab.ui.control.Label
        ReadCurveButton         matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data;
        x_1;
        focuseGear = 1;
        da;
        db;
        dc;
        dd;
        shutterTemp = 35.16;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadCurveButton
        function ReadCurveButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            app.data = load([path file]);
            rangMark = file(14:16);
            if(strcmp(rangMark,'low'))
                app.x_1   = [-10 0 20 35 50 100 150] + 50;
            elseif (strcmp(rangMark,'hig'))
                app.x_1 = [100 150 200 300 400 450 650] + 20;
            else
                app.x_1 = [500 650 800 900 1000 1300 1500] + 0;
            end
            i = 1;%定义选择的焦温档位
            curve = app.data(i,2:5);
            a = curve(1);
            b = curve(2);
            c = curve(3);
            d = curve(4);
            y = @(x)a./(exp(b./(x + 273)) - c) + d;
            
            y_1 =y(app.x_1);
            sTy16_ = y(app.shutterTemp);
            
            app.da = 0;
            app.db = 0;
            app.dc = 0;
            app.dd = 0;%定义调节量

            a_ = a + app.da;
            b_ = b + app.db;
            c_ = c + app.dc;
            d_ = d + app.dd;
            %快门温反查
            shutterTempY16 = @(temp)a_./(exp(b_ ./ (temp + 273)) - c_) + d_;
            sTy16 = shutterTempY16(app.shutterTemp);
            y_1 = y_1 + sTy16 - sTy16_;
            t = @(y16)(b_ ./ (log(a_ ./ (y16 - d_) + c_)) - 273);
            temp = t(y_1);
            plot(app.UIAxes,y_1,app.x_1,'bo',y_1,temp,'r*');

            app.UITable.Data = ["调整前",app.x_1;"调整后",temp];
            app.UITable2.Data = [curve',[a_;b_;c_;d_]];


        end

        % Value changed function: FoucsGearDropDown
        function FoucsGearDropDownValueChanged(app, event)
            value = app.FoucsGearDropDown.Value;
            value = str2double(value);
            app.focuseGear = value;
            if(value > size(app.data,1))
                masgbox('档位选择错误','提示','error');
            end
            
            curve = app.data(value,2:5);
            a = curve(1);
            b = curve(2);
            c = curve(3);
            d = curve(4);
            y = @(x)a./(exp(b./(x + 273)) - c) + d;
    
            y_1 =y(app.x_1);
            sTy16_ = y(app.shutterTemp);
            
            app.da = 0;
            app.db = 0;
            app.dc = 0;
            app.dd = 0;%定义调节量

            a_ = a + app.da;
            b_ = b + app.db;
            c_ = c + app.dc;
            d_ = d + app.dd;
            %查快门Y16
            shutterTempY16 = @(temp)a_./(exp(b_ ./ (temp + 273)) - c_) + d_;
            sTy16 = shutterTempY16(app.shutterTemp);
            y_1 = y_1 + sTy16 - sTy16_;
            t = @(y16)(b_ ./ (log(a_ ./ (y16 - d_) + c_)) - 273);
            temp = t(y_1);
            plot(app.UIAxes,y_1,app.x_1,'bo',y_1,temp,'r*');

            app.UITable.Data = ["调整前",app.x_1;"调整后",temp];
            app.UITable2.Data = [curve',[a_;b_;c_;d_]];
        end

        % Value changed function: AEditField
        function AEditFieldValueChanged(app, event)
            app.da = app.AEditField.Value;
            curve = app.data(app.focuseGear,2:5);
            a = curve(1);
            b = curve(2);
            c = curve(3);
            d = curve(4);
            y = @(x)a./(exp(b./(x + 273)) - c) + d;

            y_1 =y(app.x_1);
            sTy16_ = y(app.shutterTemp);

            a_ = a + app.da;
            b_ = b + app.db;
            c_ = c + app.dc;
            d_ = d + app.dd;
            %查快门Y16
            shutterTempY16 = @(temp)a_./(exp(b_ ./ (temp + 273)) - c_) + d_;
            sTy16 = shutterTempY16(app.shutterTemp);
            y_1 = y_1 + sTy16 - sTy16_;
            t = @(y16)(b_ ./ (log(a_ ./ (y16 - d_) + c_)) - 273);
            temp = t(y_1);
            plot(app.UIAxes,y_1,app.x_1,'bo',y_1,temp,'r*');

            app.UITable.Data = ["调整前",app.x_1;"调整后",temp];
            app.UITable2.Data = [curve',[a_;b_;c_;d_]];
        end

        % Value changed function: BEditField
        function BEditFieldValueChanged(app, event)
            app.db = app.BEditField.Value;
            curve = app.data(app.focuseGear,2:5);
            a = curve(1);
            b = curve(2);
            c = curve(3);
            d = curve(4);
            y = @(x)a./(exp(b./(x + 273)) - c) + d;

            y_1 =y(app.x_1);
            sTy16_ = y(app.shutterTemp);

            a_ = a + app.da;
            b_ = b + app.db;
            c_ = c + app.dc;
            d_ = d + app.dd;
            %查快门Y16
            shutterTempY16 = @(temp)a_./(exp(b_ ./ (temp + 273)) - c_) + d_;
            sTy16 = shutterTempY16(app.shutterTemp);
            y_1 = y_1 + sTy16 - sTy16_;
            t = @(y16)(b_ ./ (log(a_ ./ (y16 - d_) + c_)) - 273);
            temp = t(y_1);
            plot(app.UIAxes,y_1,app.x_1,'bo',y_1,temp,'r*');

            app.UITable.Data = ["调整前",app.x_1;"调整后",temp];
            app.UITable2.Data = [curve',[a_;b_;c_;d_]];
        end

        % Value changed function: CEditField
        function CEditFieldValueChanged(app, event)
            app.dc = app.CEditField.Value;
                        curve = app.data(app.focuseGear,2:5);
            a = curve(1);
            b = curve(2);
            c = curve(3);
            d = curve(4);
            y = @(x)a./(exp(b./(x + 273)) - c) + d;

            y_1 =y(app.x_1);
            sTy16_ = y(app.shutterTemp);

            a_ = a + app.da;
            b_ = b + app.db;
            c_ = c + app.dc;
            d_ = d + app.dd;
            %快门温反查
            shutterTempY16 = @(temp)a_./(exp(b_ ./ (temp + 273)) - c_) + d_;
            sTy16 = shutterTempY16(app.shutterTemp);
            y_1 = y_1 + sTy16 - sTy16_;
            t = @(y16)(b_ ./ (log(a_ ./ (y16 - d_) + c_)) - 273);
            temp = t(y_1);
            plot(app.UIAxes,y_1,app.x_1,'bo',y_1,temp,'r*');

            app.UITable.Data = ["调整前",app.x_1;"调整后",temp];
            app.UITable2.Data = [curve',[a_;b_;c_;d_]];
        end

        % Value changed function: DEditField
        function DEditFieldValueChanged(app, event)
            app.dd = app.DEditField.Value;
            curve = app.data(app.focuseGear,2:5);
            a = curve(1);
            b = curve(2);
            c = curve(3);
            d = curve(4);
            y = @(x)a./(exp(b./(x + 273)) - c) + d;

            y_1 =y(app.x_1);
            sTy16_ = y(app.shutterTemp);

            a_ = a + app.da;
            b_ = b + app.db;
            c_ = c + app.dc;
            d_ = d + app.dd;
            %快门温反查
            shutterTempY16 = @(temp)a_./(exp(b_ ./ (temp + 273)) - c_) + d_;
            sTy16 = shutterTempY16(app.shutterTemp);
            y_1 = y_1 + sTy16 - sTy16_;
            t = @(y16)(b_ ./ (log(a_ ./ (y16 - d_) + c_)) - 273);
            temp = t(y_1);
            plot(app.UIAxes,y_1,app.x_1,'bo',y_1,temp,'r*');

            app.UITable.Data = ["调整前",app.x_1;"调整后",temp];
            app.UITable2.Data = [curve',[a_;b_;c_;d_]];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'temp-Y16')
            xlabel(app.UIAxes, 'Y16')
            ylabel(app.UIAxes, 'temp/℃')
            zlabel(app.UIAxes, 'Z')
            app.ReadCurveButton = uibutton(app.UIFigure, 'push');
            app.ReadCurveButton.ButtonPushedFcn = createCallbackFcn(app, @ReadCurveButtonPushed, true);
            app.ReadCurveButton.Text = 'ReadCurve';
            app.FoucsGearDropDownLabel = uilabel(app.UIFigure);
            app.FoucsGearDropDownLabel.Text = 'FoucsGear';
            app.FoucsGearDropDown = uidropdown(app.UIFigure);
            app.FoucsGearDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.FoucsGearDropDown.ValueChangedFcn = createCallbackFcn(app, @FoucsGearDropDownValueChanged, true);
            app.FoucsGearDropDown.Value = '1';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'};
            app.UITable.RowName = {};
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.AEditField.ValueChangedFcn = createCallbackFcn(app, @AEditFieldValueChanged, true);
            app.BEditField = uieditfield(app.UIFigure, 'numeric');
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @BEditFieldValueChanged, true);
            app.CEditField = uieditfield(app.UIFigure, 'numeric');
            app.CEditField.ValueChangedFcn = createCallbackFcn(app, @CEditFieldValueChanged, true);
            app.DEditField = uieditfield(app.UIFigure, 'numeric');
            app.DEditField.ValueChangedFcn = createCallbackFcn(app, @DEditFieldValueChanged, true);
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = 'R';
            app.BLabel = uilabel(app.UIFigure);
            app.BLabel.Text = 'B';
            app.FLabel = uilabel(app.UIFigure);
            app.FLabel.Text = 'F';
            app.OLabel = uilabel(app.UIFigure);
            app.OLabel.Text = 'O';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'调整前'; '调整后'};
            app.UITable2.RowName = {};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '温度调整';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '测温参数调整';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CurveAdjust

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end